classdef Master_Ke < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EphysPanel                matlab.ui.container.Panel
        loadeventCheckBox         matlab.ui.control.CheckBox
        LoadOpenEphysEventButton  matlab.ui.control.Button
        LoadtheSpikingDataaftePhyButton  matlab.ui.control.Button
        PreprocessforOfflinesorterButton  matlab.ui.control.Button
        HistologyPanel            matlab.ui.container.Panel
        LifeCanvasButton          matlab.ui.control.Button
        CostumCellCountButton     matlab.ui.control.Button
        AllenAtlasButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AllenAtlasButton
        function AllenAtlasButtonPushed(app, event)
            neuropixels_trajectory_explorer
        end

        % Button pushed function: CostumCellCountButton
        function CostumCellCountButtonPushed(app, event)
            addpath(genpath('D:\Matlab_scripts\Allen_CCF_reg'))
            cell_count
        end

        % Button pushed function: LifeCanvasButton
        function LifeCanvasButtonPushed(app, event)
            edit lifecanvas_analysis
        end

        % Button pushed function: PreprocessforOfflinesorterButton
        function PreprocessforOfflinesorterButtonPushed(app, event)
            edit Intan_saveData_Sorter
        end

        % Button pushed function: LoadtheSpikingDataaftePhyButton
        function LoadtheSpikingDataaftePhyButtonPushed(app, event)
            [~, path] = Ephy_preProcess_s1;
            if app.loadeventCheckBox.Value == 1
                fprintf('Also automatically load the event data\n');
                strsplit = split(path, '\');
                eventfolder = join(strsplit(1:8), '\');
                txtfile =  dir([eventfolder{1}, '\*.txt']).name;
                Ephys_PreProcess_s2_addEvents(txtfile, eventfolder{1}, path)
            end 
        end

        % Button pushed function: LoadOpenEphysEventButton
        function LoadOpenEphysEventButtonPushed(app, event)
            Ephys_PreProcess_s2_addEvents
        end

        % Value changed function: loadeventCheckBox
        function loadeventCheckBoxValueChanged(app, event)
%             value = app.loadeventCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HistologyPanel = uipanel(app.UIFigure);
            app.HistologyPanel.Title = 'Histology';
            app.AllenAtlasButton = uibutton(app.HistologyPanel, 'push');
            app.AllenAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @AllenAtlasButtonPushed, true);
            app.AllenAtlasButton.Text = 'Allen Atlas';
            app.CostumCellCountButton = uibutton(app.HistologyPanel, 'push');
            app.CostumCellCountButton.ButtonPushedFcn = createCallbackFcn(app, @CostumCellCountButtonPushed, true);
            app.CostumCellCountButton.Tag = 'cell_count';
            app.CostumCellCountButton.Text = 'Costum Cell Count';
            app.LifeCanvasButton = uibutton(app.HistologyPanel, 'push');
            app.LifeCanvasButton.ButtonPushedFcn = createCallbackFcn(app, @LifeCanvasButtonPushed, true);
            app.LifeCanvasButton.Tag = 'lifecanvas_tg';
            app.LifeCanvasButton.Text = 'Life Canvas';
            app.EphysPanel = uipanel(app.UIFigure);
            app.EphysPanel.Title = 'Ephys';
            app.PreprocessforOfflinesorterButton = uibutton(app.EphysPanel, 'push');
            app.PreprocessforOfflinesorterButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessforOfflinesorterButtonPushed, true);
            app.PreprocessforOfflinesorterButton.Text = 'Preprocess for Offline sorter';
            app.LoadtheSpikingDataaftePhyButton = uibutton(app.EphysPanel, 'push');
            app.LoadtheSpikingDataaftePhyButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtheSpikingDataaftePhyButtonPushed, true);
            app.LoadtheSpikingDataaftePhyButton.Text = 'Load the Spiking Data afte Phy';
            app.LoadOpenEphysEventButton = uibutton(app.EphysPanel, 'push');
            app.LoadOpenEphysEventButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOpenEphysEventButtonPushed, true);
            app.LoadOpenEphysEventButton.Text = 'Load Open Ephys Event';
            app.loadeventCheckBox = uicheckbox(app.EphysPanel);
            app.loadeventCheckBox.ValueChangedFcn = createCallbackFcn(app, @loadeventCheckBoxValueChanged, true);
            app.loadeventCheckBox.Text = 'load event';
            app.loadeventCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Master_Ke

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end